classdef imageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        WczytajobrazekButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mysz 
        plik 
        obraz
        himage
    end
    
    methods (Access = private,Static = true)
        
        function idx = curPoint2xy(position)
            idx = ceil(position([3 1]));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x, y)
            app.plik = '';
            app.obraz = [];
            app.mysz = false;
            app.himage = [];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mysz = true;
             
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mysz = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.mysz && ~isempty(app.obraz)
                cp = app.curPoint2xy(app.UIAxes.CurrentPoint);
                max_ = [size(app.obraz, 1) size(app.obraz, 2)];
                
                if any(cp < 0) || any(max_ - cp < 0)
                    disp('poza obrazkiem')
                else
                    %disp(cp)
                    %disp(size(app.himage.CData))
                    app.himage.CData = insertMarker(app.himage.CData, [cp(2), cp(1)], 'circle', 'Color', 'red');
                end
                
            end
        end

        % Button pushed function: WczytajobrazekButton
        function WczytajobrazekButtonPushed(app, event)
            f = uigetfile({'*.png', 'obrazki png'
                           '*.bmp', 'obrazki bmp'});
            figure(app.UIFigure) 
            if isequal(f, 0)
                return
            end
            app.obraz = [];
            try
                app.obraz = imread(f);
                app.plik = f;
            catch
                app.plik = '';
                app.obraz = [];
                warndlg(['nie udało się otworzyć ' f], 'Uwaga')
            end
            app.himage = imshow(app.obraz, [], 'Parent', app.UIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[]'};
            app.WczytajobrazekButton = uibutton(app.UIFigure, 'push');
            app.WczytajobrazekButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazekButtonPushed, true);
            app.WczytajobrazekButton.Text = 'Wczytaj obrazek';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end